classdef StahlDataAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        kgm3Label                       matlab.ui.control.Label
        LOXdensityEditField             matlab.ui.control.NumericEditField
        LOXdensityEditFieldLabel        matlab.ui.control.Label
        fmmultiplierTextArea            matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        fmmultiplierTextAreaLabel       matlab.ui.control.Label
        VespulaCheckBox                 matlab.ui.control.CheckBox
        OutputsLabel                    matlab.ui.control.Label
        LOXLeadEditField_3              matlab.ui.control.EditField
        LOXLeadEditField_3Label         matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        FMVTimeEditField                matlab.ui.control.NumericEditField
        FMVTimeEditFieldLabel           matlab.ui.control.Label
        OMVTimeEditField                matlab.ui.control.NumericEditField
        OMVTimeEditFieldLabel           matlab.ui.control.Label
        LOXLeadEditField                matlab.ui.control.NumericEditField
        LOXLeadLabel                    matlab.ui.control.Label
        v0241Label                      matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        madebyJacksonStahlLabel         matlab.ui.control.Label
        OrificeandinjectorusedinterchangablyinoutputsLabel  matlab.ui.control.Label
        TEditField_2                    matlab.ui.control.NumericEditField
        TLabel                          matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        SamplingRateTextAreaLabel       matlab.ui.control.Label
        SamplingRateTextArea            matlab.ui.control.TextArea
        sLabel_3                        matlab.ui.control.Label
        fmvflowdurTextAreaLabel         matlab.ui.control.Label
        fmvflowdurTextArea              matlab.ui.control.TextArea
        sLabel_2                        matlab.ui.control.Label
        omvflowdurTextAreaLabel         matlab.ui.control.Label
        omvflowdurTextArea              matlab.ui.control.TextArea
        sLabel                          matlab.ui.control.Label
        SenseBreakTextAreaLabel         matlab.ui.control.Label
        SenseBreakTextArea              matlab.ui.control.TextArea
        fuelsyscdaTextArea              matlab.ui.control.NumericEditField
        fuelorfcdaTextArea              matlab.ui.control.NumericEditField
        venturicdTextArea               matlab.ui.control.NumericEditField
        loxsyscdaTextArea               matlab.ui.control.NumericEditField
        loxorfcdaTextArea               matlab.ui.control.NumericEditField
        psiLabel                        matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        cm2Label_4                      matlab.ui.control.Label
        cm2Label_3                      matlab.ui.control.Label
        fuelsyscdaTextAreaLabel         matlab.ui.control.Label
        fuelorfcdaTextAreaLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        cm2Label_2                      matlab.ui.control.Label
        cm2Label                        matlab.ui.control.Label
        venturicdTextAreaLabel          matlab.ui.control.Label
        loxsyscdaTextAreaLabel          matlab.ui.control.Label
        loxorfcdaTextAreaLabel          matlab.ui.control.Label
        PNPTTextAreaLabel               matlab.ui.control.Label
        PNPTTextArea                    matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        RunButton                       matlab.ui.control.Button
        datacsvEditFieldLabel           matlab.ui.control.Label
        FMVTimeTextAreaLabel            matlab.ui.control.Label
        datacsvEditField                matlab.ui.control.EditField
        WaterCheckBox                   matlab.ui.control.CheckBox
        JetACheckBox                    matlab.ui.control.CheckBox
        LN2CheckBox                     matlab.ui.control.CheckBox
        LOXCheckBox                     matlab.ui.control.CheckBox
        FMVTimeTextArea                 matlab.ui.control.TextArea
        OMVTimeTextArea                 matlab.ui.control.TextArea
        OMVTimeTextAreaLabel            matlab.ui.control.Label
        PlotsTab                        matlab.ui.container.Tab
        SwitchORUNandFRUNcheckNotionCheckBox  matlab.ui.control.CheckBox
        AvgFuelOrfCdAFlowMeterEditField  matlab.ui.control.EditField
        AvgFuelOrfCdAFlowMeterEditFieldLabel  matlab.ui.control.Label
        AvgFuelSysCdAFlowMeterEditField  matlab.ui.control.EditField
        AvgFuelSysCdAFlowMeterEditFieldLabel  matlab.ui.control.Label
        AvgFuelOrfCdASysCdAEditField    matlab.ui.control.EditField
        AvgFuelOrfCdASysCdAEditFieldLabel  matlab.ui.control.Label
        AvgFuelSysCdAOrificeEditField   matlab.ui.control.EditField
        AvgFuelSysCdAOrificeEditFieldLabel  matlab.ui.control.Label
        AvgLOXOrfCdAVenturiEditField    matlab.ui.control.EditField
        AvgLOXOrfCdAVenturiEditFieldLabel  matlab.ui.control.Label
        AvgLOXOrfCdASysCdAEditField     matlab.ui.control.EditField
        AvgLOXOrfCdASysCdAEditFieldLabel  matlab.ui.control.Label
        AvgLOXSysCdAVenturiEditField    matlab.ui.control.EditField
        AvgLOXSysCdAVenturiEditFieldLabel  matlab.ui.control.Label
        AvgLOXSysCdAOrificeEditField    matlab.ui.control.EditField
        AvgLOXSysCdAOrificeEditFieldLabel  matlab.ui.control.Label
        cm2Label_12                     matlab.ui.control.Label
        cm2Label_11                     matlab.ui.control.Label
        cm2Label_10                     matlab.ui.control.Label
        cm2Label_9                      matlab.ui.control.Label
        cm2Label_8                      matlab.ui.control.Label
        cm2Label_7                      matlab.ui.control.Label
        cm2Label_6                      matlab.ui.control.Label
        cm2Label_5                      matlab.ui.control.Label
        kgsLabel_6                      matlab.ui.control.Label
        avgfuelmdotfmTextAreaLabel      matlab.ui.control.Label
        avgfuelmdotfmTextArea           matlab.ui.control.TextArea
        kgsLabel_5                      matlab.ui.control.Label
        avgfuelmdotsysTextAreaLabel     matlab.ui.control.Label
        avgfuelmdotsysTextArea          matlab.ui.control.TextArea
        kgsLabel_4                      matlab.ui.control.Label
        avgfuelmdotorfTextAreaLabel     matlab.ui.control.Label
        avgfuelmdotorfTextArea          matlab.ui.control.TextArea
        kgsLabel_3                      matlab.ui.control.Label
        avgloxmdotventuriTextAreaLabel  matlab.ui.control.Label
        avgloxmdotventuriTextArea       matlab.ui.control.TextArea
        kgsLabel_2                      matlab.ui.control.Label
        avgloxmdotsysTextAreaLabel      matlab.ui.control.Label
        avgloxmdotsysTextArea           matlab.ui.control.TextArea
        kgsLabel                        matlab.ui.control.Label
        avgloxmdotorfTextAreaLabel      matlab.ui.control.Label
        avgloxmdotorfTextArea           matlab.ui.control.TextArea
        EngineDataButton                matlab.ui.control.Button
        CloseAllButton                  matlab.ui.control.Button
        ShowInputsCheckBox              matlab.ui.control.CheckBox
        FINPTCheckBox                   matlab.ui.control.CheckBox
        FLPTCheckBox                    matlab.ui.control.CheckBox
        CHPTCheckBox                    matlab.ui.control.CheckBox
        FIPTCheckBox                    matlab.ui.control.CheckBox
        FRUNPTCheckBox                  matlab.ui.control.CheckBox
        FTPTCheckBox                    matlab.ui.control.CheckBox
        OIPTCheckBox                    matlab.ui.control.CheckBox
        ORUNPTCheckBox                  matlab.ui.control.CheckBox
        OTPTCheckBox                    matlab.ui.control.CheckBox
        PRPT2CheckBox                   matlab.ui.control.CheckBox
        PRPT1CheckBox                   matlab.ui.control.CheckBox
        ThrustButton                    matlab.ui.control.Button
        FLOWButton                      matlab.ui.control.Button
        ODPButton                       matlab.ui.control.Button
        ValvesButton                    matlab.ui.control.Button
        CdAsButton                      matlab.ui.control.Button
        mdotsButton                     matlab.ui.control.Button
        PressureLaddersButton           matlab.ui.control.Button
        PurgeButton                     matlab.ui.control.Button
        PlotAllButton                   matlab.ui.control.Button
        LoadInputsTab                   matlab.ui.container.Tab
        SubscaleCoaxSpring2024Button    matlab.ui.control.Button
        SubscaleCoaxRemachinedButton    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        FuelSystemCdAEditField          matlab.ui.control.NumericEditField
        FuelSystemCdAEditFieldLabel     matlab.ui.control.Label
        FuelInjectorCdAEditField        matlab.ui.control.NumericEditField
        FuelInjectorCdAEditFieldLabel   matlab.ui.control.Label
        LOXSystemCdAEditField           matlab.ui.control.NumericEditField
        LOXSystemCdAEditFieldLabel      matlab.ui.control.Label
        LOXInjectorCdAEditField         matlab.ui.control.NumericEditField
        LOXInjectorCdAEditFieldLabel    matlab.ui.control.Label
        DatasetLabel                    matlab.ui.control.Label
        VespulaCoaxButton               matlab.ui.control.Button
        SubscaleAblativeButton          matlab.ui.control.Button
        ChangelogTab                    matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        times % Description
        data_name
        lox
        jeta
        %sensors begin
        PRPT1 % Description
        PRPT2 % Description
        %fuel side
        FIPT
        FLPT
        FINPT
        FTPT
        FMV
        FRUNPT
        FBANG
        FLOW
        FTLC1
        FTLC2
        FTUTC
        PUF
        %lox side
        OIPT
        OMV
        OTPT
        ORUNPT
        OBANG
        ODP
        DPPT1
        DPPT2
        OTLC1
        OTLC2
        OFMTC
        OTBTC
        OTTC1
        OTTC2
        OTTC3
        OITC
        PUO
        %others
        PNPT
        PUPT2
        THRUST
        CHPT
        RTC1
        ETC2
        ETC3
        ETC4
        IGNITER1
        IGNITER2
        SENSE
        %lox variables
        lox_mdot_cda
        lox_mdot_sys_cda
        lox_mdot_venturi
        avg_lox_mdot_orifice
        avg_lox_mdot_sys_cda
        avg_lox_mdot_venturi
        lox_sys_cda_orifice
        lox_sys_cda_venturi
        avg_lox_sys_cda_orifice
        avg_lox_sys_cda_venturi
        lox_orf_cda_sys_cda
        lox_orf_cda_venturi
        avg_lox_orf_cda_venturi
        avg_lox_orf_cda_sys_cda
        %fuel variables
        fuel_mdot_cda
        fuel_mdot_sys_cda
        fuel_mdot_fm
        avg_fuel_mdot_orifice
        avg_fuel_mdot_sys_cda
        avg_fuel_mdot_fm
        fuel_sys_cda_orifice
        fuel_sys_cda_fm
        avg_fuel_sys_cda_orifice
        avg_fuel_sys_cda_fm
        fuel_orf_cda_sys_cda
        fuel_orf_cda_fm
        avg_fuel_orf_cda_sys_cda
        avg_fuel_orf_cda_fm
        %inputs
        lox_orifice_cda
        lox_sys_cda
        venturi_cd
        fuel_orifice_cda
        fuel_sys_cda
        throat_area
        fm_multiplier

        avg_THRUST % Description
        deltat % same as dt
    end
    
    methods (Access = public)
        
        function LoadNewData(app, data)
            app.data_name = data;

            if data(end-3:end)==['.mat']
                load(data);
            elseif data(end-3:end)==['.csv']
                data = readcell(data);
            else
                app.TextArea.Value = "Cannot read this filetype";
            end
            
            app.TextArea.Value = "Running...";
            pause(0.1); % This makes the Running... box work

            %Inputs
            % LOX
            app.lox_orifice_cda = app.loxorfcdaTextArea.Value; % cm^2
            app.lox_sys_cda = app.loxsyscdaTextArea.Value; % cm^2
            app.venturi_cd = app.venturicdTextArea.Value;
            app.fm_multiplier = app.fmmultiplierTextArea.Value;
            
            % Fuel
            app.fuel_orifice_cda = app.fuelorfcdaTextArea.Value; % cm^2
            app.fuel_sys_cda = app.fuelsyscdaTextArea.Value; % cm^2
            
            % LOX Density
            switch app.lox
                case 2
                    lox_density = app.LOXdensityEditField.Value % kg/m^3, add cool prop
                case 1
                    lox_density = 740 % kg/m^3
            end
            
            % Fuel Density
            switch app.jeta
                case 2
                    fuel_density = 800 % kg/m^3
                case 1
                    fuel_density = 1000 % kg/m^3
            end
            
            % Engine
            app.throat_area = 0.001481663339628684; % m^2
            if (app.VespulaCheckBox.Value==true)
                app.throat_area = 0.00390296138; % m^2
            end
            ideal_c_star = 1650; % m/s
            
            % Interval
            %t_before_omv = app.TEditField.Value; % s, time before OMV opens to plot data
            t_start_avg = 1.5; % s, T+ time to pull average
            t_end_avg = 0.1; % s, time before OMV closes to pull average
            t_before_fmv = app.TEditField.Value; % s, time before fmv opens to plot data
            t_plus = app.TEditField_2.Value; % s, T+ time when to stop plotting
            
            %Index Sensors and Valves
            columns = size(data,2);
            time_index = 1;
            
            % Initialize sensors index to prevent error when sensor is missing
            chpt_index = 1;
            ftlc2_index = 1;
            ftlc1_index = 1;
            otlc2_index = 1;
            otlc1_index = 1;
            otutc_index = 1;
            otbtc_index = 1;
            ofmtc_index = 1;
            ftutc_index = 1;
            dppt1_index = 1;
            dppt2_index = 1;
            thrust_index = 1;
            odp2_index = 1;
            etc1_index = 1;
            etc2_index = 1;
            etc3_index = 1;
            etc4_index = 1;
            
            % Add sensors and valves into for loop
            % Use below syntax to get the index of any recorded variable, such as FTPT
            % or FBANG_V, keep the i - 1
            
            for i = 1:columns
                if strcmp(data{1,i}, 'FTPT')
                    ftpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTPT'])
                    otpt_index = i-1;
                end   
            
                if strcmp(data{1,i}, ['FMV'])
                    fmv_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OMV'])
                    omv_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PUO'])
                    puo_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PUF'])
                    puf_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OIPT'])
                    oipt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FIPT'])
                    fipt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ORUNPT'])
                    if app.SwitchORUNandFRUNcheckNotionCheckBox.Value==true
                        orunpt_index = i-1;
                    else
                        frunpt_index = i-1;
                    end
                end
            
                if strcmp(data{1,i}, ['FRUNPT'])
                    if app.SwitchORUNandFRUNcheckNotionCheckBox.Value==true
                        frunpt_index = i-1;
                    else
                        orunpt_index = i-1;
                    end
                end
            
                if strcmp(data{1,i}, ['FBANG_V'])
                    fbang_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OBANG_V'])
                    obang_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PRPT1'])
                   prpt1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PRPT2'])
                   prpt2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PNPT'])
                   pnpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FLOW'])
                   flow_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ODP'])
                   odp_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ODP2'])
                   odp2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FTLC1'])
                   ftlc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FTLC2'])
                   ftlc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTLC1'])
                   otlc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTLC2'])
                   otlc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OFMTC'])
                   ofmtc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTUTC'])
                   otutc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTBTC'])
                   otbtc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTTC1'])
                   ottc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTTC2'])
                   ottc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTTC3'])
                   ottc3_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FTUTC'])
                   otbtc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['DPPT1'])
                   dppt1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['DPPT2'])
                   dppt2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['Thrust'])
                   thrust_index = i-1;
                end
            
                if strcmp(data{1,i}, ['CHPT'])
                   chpt_index = i-1;
                end

                if strcmp(data{1,i}, ['FLPT'])
                   flpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FINPT'])
                   finpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ETC1']) | strcmp(data{1,i}, ['ETC01'])
                   etc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ETC2']) | strcmp(data{1,i}, ['ETC02'])
                   etc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ETC3']) | strcmp(data{1,i}, ['ETC03'])
                   etc3_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ETC4'])
                  etc4_index = i-1;
                end
            
                if strcmp(data{1,i}, ['Ignitor1'])
                  igniter1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['Ignitor2'])
                  igniter2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PUPT2'])
                  pupt2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OITC'])
                  oitc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['Sense'])
                  sense_index = i-1;
                end
            
            end

            % DO NOT TOUCH THIS PART, expect it to take a decent time to run
            % Separates time from other data because its easier to reduce that way
            
            app.times = [data{2:end,time_index}]-data{2,time_index};
            % Sets time to start from data start instead of from unix time
            
            pure_data = data(2:end,2:end);
            % Separated data
            
            %data formatting, -250 is a null value, consistent with AVI syntax
            pure_data(cellfun(@(x) isa(x, 'missing'), pure_data)) = {'-250.000000'};
            pure_data(cellfun(@(x) strcmp(x, 'open'), pure_data)) = {'1.0000000000'};
            pure_data(cellfun(@(x) strcmp(x, 'closed'), pure_data)) = {'0.0000000000'};
            pure_data(cellfun(@(x) (length(x) < 5), pure_data)) = {'-0.00000000'};
            
            data2 = cellfun(@(x) x(1:5),pure_data,'UniformOutput',false);
            data2 = str2double(data2);

            %Interval
            if app.jeta~=0
                local_time = [data2(1:end,fmv_index)];
            else
                local_time = [data2(1:end,omv_index)];
            end
            if app.CHPT~=0
                app.CHPT = [data2(1:end,chpt_index)];
            elseif exist('flpt_index', 'var')==1
                app.CHPT = [data2(1:end,flpt_index)];
            elseif app.lox~=0
                app.CHPT = [data2(1:end,oipt_index)];
            else
                app.CHPT = [data2(1:end,fipt_index)];
            end
            
            dt = (app.times(end)-app.times(1))/length(app.times) % s
            app.deltat = dt;
            sampling_rate = 1/dt % Hz
            app.SamplingRateTextArea.Value = num2str(sampling_rate);
            [~,data_start] = max(local_time)
            data_start = data_start - round(t_before_fmv/dt);
            
            data_end = data_start + round(t_before_fmv/dt) + round(t_plus/dt)
            [~,t_zero] = max(app.CHPT>10)
            app.times = app.times - app.times(t_zero);
            data2 = data2(data_start:data_end, :);
            app.times = app.times(data_start:data_end);
            
            %Variable Assignment
            % Pressurant
            app.PRPT1 = [data2(1:end,prpt1_index)]; 
            app.PRPT2 = [data2(1:end,prpt2_index)]; 

            if app.jeta ~= 0
                % Fuel
                app.FIPT = [data2(1:end,fipt_index)];
                app.FTPT = [data2(1:end,ftpt_index)];
                app.FMV = [data2(1:end,fmv_index)];
                app.FRUNPT = [data2(1:end,frunpt_index)];
                app.FBANG = [data2(1:end,fbang_index)];
                app.FLOW = [data2(1:end,flow_index)];
                app.FTLC1 = [data2(1:end,ftlc1_index)];
                app.FTLC2 = [data2(1:end,ftlc2_index)];
                app.FTUTC = [data2(1:end,otutc_index)];
                app.PUF = [data2(1:end,puf_index)];
                if exist('flpt_index', 'var')==1
                    app.FLPT = [data2(1:end,flpt_index)];
                else
                    app.FLPT = 0;
                end
                if exist('finpt_index', 'var')==1
                    app.FINPT = [data2(1:end,finpt_index)];
                else
                    app.FINPT = 0;
                end
            end
            
            if app.lox ~= 0
                % LOX
                app.OIPT = [data2(1:end,oipt_index)];
                app.OMV = [data2(1:end,omv_index)];
                app.OTPT = [data2(1:end,otpt_index)];
                app.ORUNPT = [data2(1:end,orunpt_index)];
                app.OBANG = [data2(1:end,obang_index)];
                app.ODP = [data2(1:end,odp_index)];
                %app.ODP2 = [data2(1:end,odp2_index)];
                app.DPPT1 = [data2(1:end,dppt1_index)];
                app.DPPT2 = [data2(1:end,dppt2_index)];
                app.OTLC1 = [data2(1:end,otlc1_index)];
                app.OTLC2 = [data2(1:end,otlc2_index)];
                app.OFMTC = [data2(1:end,ofmtc_index)];
                %app.OTUTC = [data2(1:end,otutc_index)];
                app.OTBTC = [data2(1:end,otbtc_index)];
                app.OTTC1 = [data2(1:end,ottc1_index)];
                app.OTTC2 = [data2(1:end,ottc1_index)];
                app.OTTC3 = [data2(1:end,ottc1_index)];
                app.OITC = [data2(1:end,oitc_index)];
                app.PUO = [data2(1:end,puo_index)];
            end
            
            % Pneumatics
            app.PNPT = [data2(1:end,pnpt_index)];
            
            % Purge
            app.PUPT2 = [data2(1:end,pupt2_index)];
            
            if app.jeta==2 && app.lox==2
                % Engine
                app.THRUST = [data2(1:end,thrust_index)];
                app.CHPT = [data2(1:end,chpt_index)];
                %app.ETC1 = [data2(1:end,etc1_index)];
                %app.ETC2 = [data2(1:end,etc2_index)];
                %app.ETC3 = [data2(1:end,etc3_index)];
                %app.ETC4 = [data2(1:end,etc4_index)];
                app.IGNITER1 = [data2(1:end,igniter1_index)];
                app.IGNITER2 = [data2(1:end,igniter2_index)];
                app.SENSE = [data2(1:end,sense_index)];
            end
        
            %init end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % %Raw Plots
            % % Tank Load Cells
            % dOTLC1 = (OTLC1(1) - OTLC1(end))/2.205; % kg
            % dOTLC2 = (OTLC2(1) - OTLC2(end))/2.205; % kg
            % dOTLC = dOTLC1 + dOTLC2;
            % dFTLC1 = (FTLC1(1) - FTLC1(end))/2.205; % kg
            % dFTLC2 = (FTLC2(1) - FTLC2(end))/2.205; % kg
            % dFTLC = dFTLC1 + dFTLC2;
            % figure
            % hold on
            % yyaxis left
            % plot(app.times, OTLC1/2.205, 'b-')
            % plot(app.times, OTLC2/2.205, 'b-')
            % plot(app.times, FTLC1/2.205, 'r-')
            % plot(app.times, FTLC2/2.205, 'r-')
            % title('Tank Load Cells')
            % subtitle(data_name, 'Interpreter', 'none')
            % ylabel('Mass (kg)')
            % xlabel('Time (s)')
            % yyaxis right
            % plot(app.times, zeros(length(app.times))+dOTLC, 'c-')
            % plot(app.times, zeros(length(app.times))+dFTLC, 'm-')
            % legend('OTLC1', 'OTLC2', 'FTLC1', 'FTLC2', 'dOTLC', 'dFTLC')
            % hold off
            
            % % Plot LOX Temps
            % figure
            % hold on
            % plot(app.times, OTBTC)
            % plot(app.times, OTUTC)
            % plot(app.times, OFMTC)
            % plot(app.times, OTTC1)
            % plot(app.times, OTTC2)
            % plot(app.times, OTTC3)
            % plot(app.times, OITC)
            % title('LOX Temperatures')
            % subtitle(data_name, 'Interpreter', 'none')
            % ylabel('Temperature (K)')
            % xlabel('Time (s)')
            % legend('OTBTC','OTUTC','OFMTC', 'OTTC1', 'OTTC2', 'OTTC3', 'OITC')
            % hold off
            
            % % Plot Fuel Temps
            % figure
            % hold on
            % plot(app.times, FTUTC)
            % title('Fuel Temperatures')
            % subtitle(data_name, 'Interpreter', 'none')
            % ylabel('Temperature (K)')
            % xlabel('Time (s)')
            % legend('FTUTC')
            % hold off
            
            % % Plot Engine Temps
            % figure
            % hold on
            % plot(app.times, ETC1)
            % plot(app.times, ETC2)
            % plot(app.times, ETC3)
            % % plot(app.times, ETC4)
            % title('Engine Temperatures')
            % subtitle(data_name, 'Interpreter', 'none')
            % ylabel('Temperature (K)')
            % xlabel('Time (s)')
            % legend('ETC1', 'ETC2', 'ETC3', 'ETC4')
            % hold off            
            
            %Data Reduction
            % Valve Timings
            if app.lox~=0
                OIPT_mask = app.OIPT > 10;
                [~,i_OMV] = max(app.OMV);
                [~,i_OIPT] = max(OIPT_mask);
                OMV_open_time = app.times(i_OIPT)-app.times(i_OMV)
                OMV_open_time = round(OMV_open_time, 3);
                app.OMVTimeTextArea.Value = num2str(OMV_open_time*1000)
            end
            if app.jeta~=0
                FIPT_mask = app.FIPT > 10;
                [~,i_FMV] = max(app.FMV);
                [~,i_FIPT] = max(FIPT_mask);
                FMV_open_time = app.times(i_FIPT)-app.times(i_FMV)
                FMV_open_time = round(FMV_open_time, 3);
                app.FMVTimeTextArea.Value = num2str(FMV_open_time*1000)
            end
            
            loxaccount = app.LOXLeadEditField.Value-(app.FMVTimeEditField.Value-app.OMVTimeEditField.Value);
            app.LOXLeadEditField_3.Value = num2str(1000*(FMV_open_time-OMV_open_time)+loxaccount);

            % Sense Wire Break Time
            SENSE_mask = app.SENSE > 4;
            [~,i_SENSE_break] = min(SENSE_mask);
            [~,i_IGNITER1] = max(app.IGNITER1);
            Sense_break_time = app.times(i_SENSE_break)-app.times(i_IGNITER1);
            app.SenseBreakTextArea.Value = num2str(Sense_break_time);
            
            % Average Mask
            % Mask for Interval where Average is Taken
            if app.jeta~=0
                FMV_mask = app.FMV > 0.5;
            else
                FMV_mask = app.OMV > 0.5;
            end
            if app.CHPT~=0
                CHPT_mask = app.CHPT > 10;
            elseif exist('flpt_index', 'var')==1
                CHPT_mask = app.FLPT > 10;
            elseif app.lox~=0
                CHPT_mask = app.OIPT > 10;
            else
                CHPT_mask = app.FIPT > 10;
            end
            [~, i_start] = max(CHPT_mask);
            if app.jeta~=0
                [~, i_shift] = max(app.FMV);
            else
                [~, i_shift] = max(app.OMV);
            end
            [~, i_end] = max(app.times(FMV_mask));
            i_end = i_end + i_shift;
            i = false(length(app.times));
            i_start = i_start + round(t_start_avg/dt);
            i_end = i_end - round(t_end_avg/dt);
            i(i_start:i_end) = true;
            

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % % % % %LOX Side
            if app.lox~=0
                % LOX Mdot from Orifce CdA
                app.lox_mdot_cda = app.lox_orifice_cda./100^2.*sqrt(2.*lox_density.*abs(app.OIPT-app.CHPT).*6895);
    
                % LOX Mdot from System CdA
                app.lox_mdot_sys_cda = app.lox_sys_cda./100^2.*sqrt(2.*lox_density.*abs(app.OTPT-app.OIPT).*6895);
    
                % LOX Mdot from Load Cells
                OMV_mask = app.OMV > 0.5;
                omv_flow_duration = max(app.times(OMV_mask)) - min(app.times(OMV_mask));
                app.omvflowdurTextArea.Value = num2str(omv_flow_duration);
    
                % LOX Venturi
                inlet_ID = 0.844; % in
                throat_ID = 0.4375; % in
                app.lox_mdot_venturi = app.venturi_cd*pi*(inlet_ID/39.37/2)^2*sqrt(2*lox_density*abs(app.ODP)*6895/(((pi*(inlet_ID/39.37/2)^2)/(pi*(throat_ID/39.37/2)^2))^2-1));
    
                % Average Mdots
                app.avg_lox_mdot_orifice = mean(app.lox_mdot_cda(i)) % kg/s
                app.avg_lox_mdot_sys_cda = mean(app.lox_mdot_sys_cda(i)) % kg/s
                app.avg_lox_mdot_venturi = mean(app.lox_mdot_venturi(i)) % kg/s

                app.avgloxmdotorfTextArea.Value = num2str(app.avg_lox_mdot_orifice);
                app.avgloxmdotsysTextArea.Value = num2str(app.avg_lox_mdot_sys_cda);
                app.avgloxmdotventuriTextArea.Value = num2str(app.avg_lox_mdot_venturi);
                % 
                % System CdA
                app.lox_sys_cda_venturi = app.lox_mdot_venturi./sqrt(2*lox_density*abs((app.OTPT-app.OIPT)+.1)*6895)*100^2; % cm^2
                app.lox_sys_cda_orifice = app.lox_mdot_cda./sqrt(2*lox_density*abs((app.OTPT-app.OIPT)+.1)*6895)*100^2; % cm^2
    
                % Average System CdA
                app.avg_lox_sys_cda_orifice = mean(app.lox_sys_cda_orifice(i)) % cm^2
                app.avg_lox_sys_cda_venturi = mean(app.lox_sys_cda_venturi(i)); % cm^2
    
                %avg_lox_sys_cda_lc = mean(lox_sys_cda_lc(i)) % cm^2
                % 
                % Injector CdA (Verifying Injector CdA with other mdot measurements)
                app.lox_orf_cda_venturi = app.lox_mdot_venturi./sqrt(2*lox_density*abs(app.OIPT-app.CHPT)*6895)*100^2; % cm^2
                app.lox_orf_cda_sys_cda = app.lox_mdot_sys_cda./sqrt(2*lox_density*abs(app.OIPT-app.CHPT)*6895)*100^2; % cm^2
                app.avg_lox_orf_cda_venturi = mean(app.lox_orf_cda_venturi(i)) % cm^2
                app.avg_lox_orf_cda_sys_cda = mean(app.lox_orf_cda_sys_cda(i)) % cm^2

                app.AvgLOXSysCdAOrificeEditField.Value = num2str(app.avg_lox_sys_cda_orifice);
                app.AvgLOXSysCdAVenturiEditField.Value = num2str(app.avg_lox_sys_cda_venturi);
                app.AvgLOXOrfCdASysCdAEditField.Value = num2str(app.avg_lox_orf_cda_venturi);
                app.AvgLOXOrfCdAVenturiEditField.Value = num2str(app.avg_lox_orf_cda_sys_cda);
                % % 
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % % Fuel Side
            if app.jeta~=0
                % Mdot from Orifce CdA
                app.fuel_mdot_cda = app.fuel_orifice_cda./100^2.*sqrt(2.*fuel_density.*abs(app.FIPT-app.CHPT).*6895);
    
                % Mdot from Orifce CdA
                app.fuel_mdot_sys_cda = app.fuel_sys_cda./100^2.*sqrt(2.*fuel_density.*abs(app.FTPT-app.FIPT).*6895);
                
                % Fuel Flow Meter
                app.fuel_mdot_fm = app.FLOW.*fuel_density/1000;
                app.fuel_mdot_fm = app.fuel_mdot_fm .* app.fm_multiplier;
                j(app.fuel_mdot_fm>0.2) = true;
                %[~, j_first] = max(j);
                %[~, i_first] = max(i);
                %fm_lag = j_first-i_first;
                %j_last = j_first + (length(i) - fm_lag);
                %j(j_last:end) = false;
                j(1:end/2)=false;
                j((2/3)*end:end)=false;
                
                % Mdot from Load Cells
                FMV_mask = app.FMV > 0.5;
                fmv_flow_duration = max(app.times(FMV_mask)) - min(app.times(FMV_mask));
                app.fmvflowdurTextArea.Value = num2str(fmv_flow_duration);
    
                % Average Fuel Mdots
                app.avg_fuel_mdot_orifice = mean(app.fuel_mdot_cda(i)); % kg/s
                app.avg_fuel_mdot_sys_cda = mean(app.fuel_mdot_sys_cda(i)); % kg/s
                app.avg_fuel_mdot_fm = mean(app.fuel_mdot_fm(j)); % kg/s

                app.avgfuelmdotorfTextArea.Value = num2str(app.avg_fuel_mdot_orifice);
                app.avgfuelmdotsysTextArea.Value = num2str(app.avg_fuel_mdot_sys_cda);
                app.avgfuelmdotfmTextArea.Value = num2str(app.avg_fuel_mdot_fm);

                % System CdA
                app.fuel_sys_cda_fm = app.fuel_mdot_fm./sqrt(2.*fuel_density.*abs((app.FTPT-app.FIPT)+0.1)*6895).*100^2; % cm^2
                app.fuel_sys_cda_orifice = app.fuel_mdot_cda./sqrt(2.*fuel_density.*abs((app.FTPT-app.FIPT)+0.1)*6895).*100^2; % cm^2

                % Average System CdA
                app.avg_fuel_sys_cda_orifice = mean(app.fuel_sys_cda_orifice(i)); % cm^2
                app.avg_fuel_sys_cda_fm = mean(app.fuel_sys_cda_fm(j)); % cm^2
    
                % Injector CdA (Verifying Injector CdA with other mdot measurements)
                app.fuel_orf_cda_fm = app.fuel_mdot_fm./sqrt(2*fuel_density*abs(app.FIPT-app.CHPT)*6895)*100^2; % cm^2
                app.fuel_orf_cda_sys_cda = app.fuel_mdot_sys_cda./sqrt(2*fuel_density*abs(app.FIPT-app.CHPT)*6895)*100^2; % cm^2
                app.avg_fuel_orf_cda_fm = mean(app.fuel_orf_cda_fm(j)); % cm^2
                app.avg_fuel_orf_cda_sys_cda = mean(app.fuel_orf_cda_sys_cda(i)); % cm^2

                app.AvgFuelSysCdAOrificeEditField.Value = num2str(app.avg_fuel_sys_cda_orifice);
                app.AvgFuelSysCdAFlowMeterEditField.Value = num2str(app.avg_fuel_sys_cda_fm);
                app.AvgFuelOrfCdASysCdAEditField.Value = num2str(app.avg_fuel_orf_cda_sys_cda);
                app.AvgFuelOrfCdAFlowMeterEditField.Value = num2str(app.avg_fuel_orf_cda_fm);
                % % 
            end
            % 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.PNPTTextArea.Value = num2str(app.PNPT(1));
            % 
            % % %Engine
            % % % Select Mdot Measurement Method
            % % lox_mdot = lox_mdot_cda;
            % % fuel_mdot = fuel_mdot_sys_cda;
            % 
            % % % Everything Engine Plot
            % % figure
            % % hold on
            % % yyaxis left
            % % ylabel('Pressure (psig)')
            % % % plot(app.times, CHPT, 'g-')
            % % plot(app.times, OIPT, 'c-')
            % % plot(app.times, FIPT, 'm-')
            % % yyaxis right
            % % ylabel('Mass Flow (kg/s)')
            % % plot(app.times, lox_mdot, 'b-')
            % % plot(app.times, fuel_mdot, 'r-')
            % % plot(app.times, lox_mdot./fuel_mdot, '-')
            % % ylim([0 3])
            % % legend('OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            % % xlabel('Time (s)')
            % % title('Engine Data')
            % % subtitle(data_name, 'Interpreter', 'none')
            % % hold off
            % 
            % % % % All Liquid Pressures
            % figure
            % hold on
            % ylabel('Pressure (psig)')
            % plot(app.times, OTPT, 'b-')
            % plot(app.times, FTPT, 'r-')
            % plot(app.times, ORUNPT, 'c-')
            % plot(app.times, FRUNPT, 'm-')
            % plot(app.times, OIPT, 'b-')
            % plot(app.times, FIPT, 'r-')
            % plot(app.times, CHPT, 'g-')
            % % plot(app.times, DPPT1, '-')
            % % plot(app.times, DPPT2, '-')
            % legend('OTPT', 'FTPT', 'ORUNPT', 'FRUNPT', 'OIPT', 'FIPT', 'CHPT')
            % xlabel('Time (s)')
            % title('All Liquid Pressures')
            % subtitle(data_name, 'Interpreter', 'none')
            % hold off
            % 

            if (app.lox==2 && app.jeta==2)
                app.avg_THRUST = mean(app.THRUST(i));
            end

            app.TextArea.Value = "Finished!";
            pause(5);
            app.TextArea.Value = "Not running.";
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end
    
    methods (Access = private)
        
        function PurgePlot(app)
            % Purge
            figure
            hold on
            plot(app.times, app.PUPT2)
            title('Purge Pressure')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Pressure (psig)')
            xlabel('Time (s)')
            hold off
        end

        function PressuresPlots(app)
            % % %LOX Side
            % % Full Pressure Ladder Plot
            if app.lox~=0
                legendcell = [];
                figure
                hold on
                if (app.PRPT1CheckBox.Value==true)
                    plot(app.times, app.PRPT1, 'b-')
                    legendcell = [legendcell, {'PRPT1'}];
                end
                if (app.PRPT2CheckBox.Value==true)
                    plot(app.times, app.PRPT2, 'g-')
                    legendcell = [legendcell, {'PRPT2'}];
                end
                if (app.OTPTCheckBox.Value==true)
                    plot(app.times, app.OTPT, 'c-')
                    legendcell = [legendcell, {'OTPT'}];
                end
                if (app.ORUNPTCheckBox.Value==true)
                    plot(app.times, app.ORUNPT, 'm-')
                    legendcell = [legendcell, {'ORUNPT'}];
                end
                if (app.OIPTCheckBox.Value==true)
                    plot(app.times, app.OIPT, 'r-')
                    legendcell = [legendcell, {'OIPT'}];
                end
                if (app.CHPTCheckBox.Value==true)
                    plot(app.times, app.CHPT, 'b-')
                    legendcell = [legendcell, {'CHPT'}];
                end
                ylabel('Pressure (psig)')
                title('LOX Pressure Ladder')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend(legendcell)
                xlabel('Time (s)')
                hold off
    
                % % Pressure Ladder Plot (Liquid Only)
                % figure
                % hold on
                % plot(app.times, app.OTPT, 'c-')
                % plot(app.times, app.ORUNPT, 'm-')
                % plot(app.times, app.OIPT, 'r-')
                % %plot(app.times, app.CHPT, 'b-')
                % ylabel('Pressure (psig)')
                % title('LOX Pressure Ladder (Liquid Only)')
                % subtitle(app.data_name, 'Interpreter', 'none')
                % legend('OTPT', 'ORUNPT', 'OIPT', 'CHPT')
                % xlabel('Time (s)')
                % hold off
            end

            % Fuel Side
            % Full Pressure Ladder Plot
            if app.jeta~=0
                legendcell = [];
                figure
                hold on
                if (app.PRPT1CheckBox.Value==true)
                    plot(app.times, app.PRPT1, 'b-')
                    legendcell = [legendcell, {'PRPT1'}];
                end
                if (app.PRPT2CheckBox.Value==true)
                    plot(app.times, app.PRPT2, 'g-')
                    legendcell = [legendcell, {'PRPT2'}];
                end
                if (app.FTPTCheckBox.Value==true)
                    plot(app.times, app.FTPT, 'c-')
                    legendcell = [legendcell, {'FTPT'}];
                end
                if (app.FRUNPTCheckBox.Value==true)
                    plot(app.times, app.FRUNPT, 'm-')
                    legendcell = [legendcell, {'FRUNPT'}];
                end
                if (app.FIPTCheckBox.Value==true)
                    plot(app.times, app.FIPT, 'r-')
                    legendcell = [legendcell, {'FIPT'}];
                end
                if (app.FLPTCheckBox.Value==true && app.FLPT~=0)
                    plot(app.times, app.FLPT, 'r.')
                    legendcell = [legendcell, {'FLPT'}];
                end
                if (app.FINPTCheckBox.Value==true && app.FINPT~=0)
                    plot(app.times, app.FINPT, 'r--')
                    legendcell = [legendcell, {'FINPT'}];
                end
                if (app.CHPTCheckBox.Value==true)
                    plot(app.times, app.CHPT, 'b-')
                    legendcell = [legendcell, {'CHPT'}];
                end
                ylabel('Pressure (psig)')
                title('Fuel Pressure Ladder')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend(legendcell)
                xlabel('Time (s)')
                hold off
                % 
                % % % Pressure Ladder Plot (Liquid Only)
                % figure
                % hold on
                % plot(app.times, app.FTPT, 'c-')
                % plot(app.times, app.FRUNPT, 'm-')
                % plot(app.times, app.FIPT, 'r-')
                % %plot(app.times, FLPT, 'r.')
                % %plot(app.times, FINPT, 'r--')
                % plot(app.times, app.CHPT, 'b-')
                % ylabel('Pressure (psig)')
                % title('Fuel Pressure Ladder (Liquid Only)')
                % subtitle(app.data_name, 'Interpreter', 'none')
                % legend('FTPT', 'FRUNPT', 'FIPT', 'CHPT')
                % xlabel('Time (s)')
                % hold off
            end
        end

        function MDotPlots(app)
            if app.lox~=0
                % % LOX Mdot Plot
                figure
                hold on
                plot(app.times, app.lox_mdot_cda, '-b');
                plot(app.times, app.lox_mdot_sys_cda, '-c');
                plot(app.times, app.lox_mdot_venturi, '-r');

                plot(app.times, zeros(length(app.times),1) + app.avg_lox_mdot_orifice, 'b--')
                plot(app.times, zeros(length(app.times),1) + app.avg_lox_mdot_sys_cda, 'c--')
                plot(app.times, zeros(length(app.times),1) + app.avg_lox_mdot_venturi, 'r--')

                title('LOX Mass Flow')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('Mass Flow (kg/s)')
                ylim([0 3])
                legend('Injector CdA', 'System CdA', 'Venturi')
                
                if (app.ShowInputsCheckBox.Value==true)
                    ooricda = app.lox_orifice_cda;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice CdA = ', ooricda];
                    ooricda(20) = ' ';
    
                    ooridp = mean(app.OIPT)-mean(app.CHPT);
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['Orifice dP = ' ooridp];
                    ooridp(20) = ' ';
    
                    osyscda = app.lox_sys_cda;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['System CdA = ', osyscda];
                    osyscda(20) = ' ';
    
                    osysdp = mean(app.OTPT)-mean(app.OIPT);
                    osysdp = num2str(round(osysdp, 3));
                    osysdp = ['System dP = ' osysdp];
                    osysdp(20) = ' ';
    
                    annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', [ooricda; ooridp; osyscda; osysdp])
                end
                hold off
            end

            if app.jeta~=0
                % % Fuel Mdot Plot
                figure
                hold on
                plot(app.times, app.fuel_mdot_cda, '-b');
                plot(app.times, app.fuel_mdot_sys_cda, '-c');
                plot(app.times, app.fuel_mdot_fm, '-r');
                plot(app.times, zeros(length(app.times),1) + app.avg_fuel_mdot_orifice, '--b');
                plot(app.times, zeros(length(app.times),1) + app.avg_fuel_mdot_sys_cda, '--c');
                plot(app.times, zeros(length(app.times),1) + app.avg_fuel_mdot_fm, '--r');
                title('Fuel Mass Flow')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('Mass Flow (kg/s)')
                legend('Injector CdA', 'System CdA', 'Flow Meter')
                if (app.ShowInputsCheckBox.Value==true)
                    foricda = app.fuel_orifice_cda;
                    foricda = num2str(round(foricda, 3));
                    foricda = ['Orifice CdA = ', foricda];
                    foricda(20) = ' ';
    
                    foridp = mean(app.FIPT)-mean(app.CHPT);
                    foridp = num2str(round(foridp, 3));
                    foridp = ['Orifice dP = ' foridp];
                    foridp(20) = ' ';
    
                    fsyscda = app.fuel_sys_cda;
                    fsyscda = num2str(round(fsyscda, 3));
                    fsyscda = ['System CdA = ', fsyscda];
                    fsyscda(20) = ' ';
    
                    fsysdp = mean(app.FTPT)-mean(app.FIPT);
                    fsysdp = num2str(round(fsysdp, 3));
                    fsysdp = ['System dP = ' fsysdp];
                    fsysdp(20) = ' ';
    
                    annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', [foricda; foridp; fsyscda; fsysdp])
                end
                hold off
            end
        end

        function CdAsPlots(app)
            if app.lox~=0
                % % Plot System CdA
                figure
                hold on
                plot(app.times, app.lox_sys_cda_orifice, '-b')
                plot(app.times, app.lox_sys_cda_venturi, '-r')
                plot(app.times, zeros(length(app.lox_sys_cda_orifice))+app.avg_lox_sys_cda_orifice, '--b')
                plot(app.times, zeros(length(app.lox_sys_cda_venturi))+app.avg_lox_sys_cda_venturi, '--r')
                title('LOX System CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Orifice', 'Venturi')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.lox_sys_cda*1.5])
                hold off

                % Plot Injector CdA
                OIPT_mask = app.OIPT > 10;
                figure
                hold on


                plot(app.times(OIPT_mask), app.lox_orf_cda_sys_cda(OIPT_mask), '-c');
                plot(app.times(OIPT_mask), app.lox_orf_cda_venturi(OIPT_mask), '-r');
                plot(app.times(OIPT_mask), zeros(length(app.lox_orf_cda_venturi(OIPT_mask)))+app.lox_orifice_cda, '--g');
                plot(app.times(OIPT_mask), zeros(length(app.lox_orf_cda_sys_cda(OIPT_mask)))+app.avg_lox_orf_cda_sys_cda, '--c');
                plot(app.times(OIPT_mask), zeros(length(app.lox_orf_cda_venturi(OIPT_mask)))+app.avg_lox_orf_cda_venturi, '--r');
                title('LOX Injector CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('System CdA', 'Venturi')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.lox_orifice_cda*1.5])
                hold off
            end

            if app.jeta~=0
                % % Plot System CdA
                figure
                hold on
                plot(app.times, app.fuel_sys_cda_orifice, '-b');
                plot(app.times, app.fuel_sys_cda_fm, '-r');
                plot(app.times, zeros(length(app.fuel_sys_cda_orifice))+app.avg_fuel_sys_cda_orifice, '--b');
                plot(app.times, zeros(length(app.fuel_sys_cda_fm))+app.avg_fuel_sys_cda_fm, '--r');
                title('Fuel System CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Injector', 'Flow Meter')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                hold off

                % Plot Injector CdA
                FIPT_mask = app.FIPT > 10;
                figure
                hold on
                plot(app.times(FIPT_mask), app.fuel_orf_cda_sys_cda(FIPT_mask), '-c');
                plot(app.times(FIPT_mask), app.fuel_orf_cda_fm(FIPT_mask), '-r');
                plot(app.times(FIPT_mask), zeros(length(app.fuel_orf_cda_fm(FIPT_mask)))+app.fuel_orifice_cda, '--g');
                plot(app.times(FIPT_mask), zeros(length(app.fuel_orf_cda_fm(FIPT_mask)))+app.avg_fuel_orf_cda_sys_cda, '--c');
                plot(app.times(FIPT_mask), zeros(length(app.fuel_orf_cda_fm(FIPT_mask)))+app.avg_fuel_orf_cda_fm, '--r');
                title('Fuel Injector CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.fuel_orifice_cda*1.5])
                legend('System CdA', 'Flow Meter', 'Injector CdA')
                hold off
            end
        end
    
        function ValvesPlot(app)
            % Sense Wire Break Time
            SENSE_mask = app.SENSE > 4;
            
            legendcell = [];

            % % Plot Valves / Igniters
            figure
            hold on
            ylabel('Valve State')
            yyaxis left
            if app.lox~=0
                plot(app.times, app.OIPT, 'c-')
                legendcell = [legendcell, {'OIPT'}];
            end
            if app.jeta~=0
                exist('finpt_index', 'var')
                plot(app.times, app.FIPT, 'm-')
                legendcell = [legendcell, {'FIPT'}];
                if app.FLPT~=0
                    plot(app.times, app.FLPT, 'r-')
                    legendcell = [legendcell, {'FLPT'}];
                end
                if app.FINPT~=0
                    plot(app.times, app.FINPT, 'r--')
                    legendcell = [legendcell, {'FINPT'}];
                end
            end
            ylabel('Pressure (psig)')
            yyaxis right
            if app.lox~=0
                plot(app.times, app.OMV, 'b-x')
                legendcell = [legendcell, {'OMV'}];
            end
            if app.jeta~=0
                plot(app.times, app.FMV, 'r-o')
                legendcell = [legendcell, {'FMV'}];
            end
            if app.lox~=0
                plot(app.times, app.PUO, 'g-')
                legendcell = [legendcell, {'PUO'}];
            end
            if app.jeta~=0
                plot(app.times, app.PUF,'y-')
                legendcell = [legendcell, {'PUF'}];
            end
            if app.lox==2 && app.jeta==2
                plot(app.times, app.IGNITER1, 'r--')
                plot(app.times, app.IGNITER2,'m--')
                plot(app.times, SENSE_mask,'g--')
                legendcell = [legendcell, {'IGNITER1'}, {'IGNITER2'}, {'SENSE'}];
            end
            title('Valve Timings')
            subtitle(app.data_name, 'Interpreter', 'none')
            xlabel('Time (s)')
            legend(legendcell)
            hold off
        end
    
        function ODPPlot(app)
            % % Plot ODP
            figure
            hold on
            plot(app.times, app.ODP)
            title('Venturi ODP')
            subtitle(app.data_name, 'Interpreter', 'none')
            legend('ODP')
            hold off
        end
        function FLOWPlot(app)
            % % Plot FM
            figure
            hold on
            plot(app.times, app.FLOW)
            title('Flow Meter DP')
            subtitle(app.data_name, 'Interpreter', 'none')
            legend('Flow Meter')
            hold off
        end

        function ThrustPlots(app)
            % % Thrust
            THRUST_zero = mean(app.THRUST(1:round(1/app.deltat)));
            app.THRUST = abs(app.THRUST-THRUST_zero);
            figure
            hold on
            yyaxis left
            plot(app.times, app.THRUST, 'b-')
            plot(app.times, zeros(length(app.THRUST),1)-app.avg_THRUST, 'b--')
            plot(app.times, zeros(length(app.THRUST),1)+THRUST_zero, 'g--')
            title('Thrust')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Load (lbf)')
            xlabel('Time (s)')
            yyaxis right
            plot(app.times, app.CHPT, 'r-')
            ylabel('Pressure (psig)')
            legend('Tared Thrust', 'Avg Thrust', 'Tare', 'CHPT')
            hold off

            % Thrust Coefficient
            thrust_coefficient = app.THRUST.*4.448./(app.CHPT.*6895.*app.throat_area);

            % % Thrust Coefficient Plot
            figure
            hold on
            yyaxis left
            plot(app.times, app.THRUST, 'r')
            ylabel('Thrust (lbf)')
            yyaxis right
            plot(app.times, thrust_coefficient, 'c--')
            ylim([0 2])
            ylabel('Thrust Coefficient')
            xlabel('Time (s)')
            title('Thrust Coefficient')
            subtitle(app.data_name, 'Interpreter', 'none')
            legend('Thrust', 'Thrust Coefficient')
            hold off
        end

        function EngineDataPlot(app)
            %Engine
            % Select Mdot Measurement Method
            lox_mdot = app.lox_mdot_venturi;
            fuel_mdot_flomtr = app.fuel_mdot_fm; %named weird bc app tries to reference different variable
            fuel_mdot_syscda = app.fuel_mdot_sys_cda; %same
            fuel_mdot_injcda = app.fuel_mdot_cda;
            
            % Everything Engine Plot fm
            figure
            hold on
            yyaxis left
            ylabel('Pressure (psig)')
            plot(app.times, app.CHPT, 'g-')
            plot(app.times, app.OIPT, 'c-')
            plot(app.times, app.FIPT, 'm-')
            yyaxis right
            ylabel('Mass Flow (kg/s)')
            plot(app.times, lox_mdot, 'b-')
            plot(app.times, fuel_mdot_flomtr, 'r-')
            plot(app.times, lox_mdot./fuel_mdot_flomtr, '-')
            ylim([0 3])
            legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            xlabel('Time (s)')
            title('Engine Data - Flow Meter')
            subtitle(app.data_name, 'Interpreter', 'none')
            hold off

            % Everything Engine Plot sys cda
            figure
            hold on
            yyaxis left
            ylabel('Pressure (psig)')
            plot(app.times, app.CHPT, 'g-')
            plot(app.times, app.OIPT, 'c-')
            plot(app.times, app.FIPT, 'm-')
            yyaxis right
            ylabel('Mass Flow (kg/s)')
            plot(app.times, lox_mdot, 'b-')
            plot(app.times, fuel_mdot_syscda, 'r-')
            plot(app.times, lox_mdot./fuel_mdot_syscda, '-')
            ylim([0 3])
            legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            xlabel('Time (s)')
            title('Engine Data - System CdA')
            subtitle(app.data_name, 'Interpreter', 'none')
            hold off

            % Everything Engine Plot inj cda
            figure
            hold on
            yyaxis left
            ylabel('Pressure (psig)')
            plot(app.times, app.CHPT, 'g-')
            plot(app.times, app.OIPT, 'c-')
            plot(app.times, app.FIPT, 'm-')
            yyaxis right
            ylabel('Mass Flow (kg/s)')
            plot(app.times, lox_mdot, 'b-')
            plot(app.times, fuel_mdot_injcda, 'r-')
            plot(app.times, lox_mdot./fuel_mdot_injcda, '-')
            ylim([0 3])
            legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            xlabel('Time (s)')
            title('Engine Data - Injector CdA')
            subtitle(app.data_name, 'Interpreter', 'none')
            hold off
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LOXCheckBox
        function LOXCheckBoxValueChanged(app, event)
            value = app.LOXCheckBox.Value;
            if value == true
                app.LN2CheckBox.Value = false;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.TextArea.Value = "Processing...";
            pause(0.1); % This makes the Running... box work
            if app.LOXCheckBox.Value == true
                app.lox = 2
            elseif app.LN2CheckBox.Value == true
                app.lox = 1
            else 
                app.lox = 0
            end

            if app.JetACheckBox.Value == true
                app.jeta = 2
            elseif app.WaterCheckBox.Value == true
                app.jeta = 1
            else
                app.jeta = 0
            end

            LoadNewData(app, app.datacsvEditField.Value);
        end

        % Value changed function: LN2CheckBox
        function LN2CheckBoxValueChanged(app, event)
            value = app.LN2CheckBox.Value;
            if value == true
                app.LOXCheckBox.Value = false;
            end
        end

        % Value changed function: JetACheckBox
        function JetACheckBoxValueChanged(app, event)
            value = app.JetACheckBox.Value;
            if value == true
                app.WaterCheckBox.Value = false;
            end
        end

        % Value changed function: WaterCheckBox
        function WaterCheckBoxValueChanged(app, event)
            value = app.WaterCheckBox.Value;
            if value == true
                app.JetACheckBox.Value = false;
            end
        end

        % Button pushed function: PurgeButton
        function PurgeButtonPushed(app, event)
            PurgePlot(app);
        end

        % Button pushed function: PressureLaddersButton
        function PressureLaddersButtonPushed(app, event)
            PressuresPlots(app);
        end

        % Button pushed function: mdotsButton
        function mdotsButtonPushed(app, event)
            MDotPlots(app);
        end

        % Button pushed function: CdAsButton
        function CdAsButtonPushed(app, event)
            CdAsPlots(app);
        end

        % Button pushed function: ValvesButton
        function ValvesButtonPushed(app, event)
            ValvesPlot(app);
        end

        % Button pushed function: ODPButton
        function ODPButtonPushed(app, event)
            ODPPlot(app);
        end

        % Button pushed function: FLOWButton
        function FLOWButtonPushed(app, event)
            FLOWPlot(app);
        end

        % Button pushed function: ThrustButton
        function ThrustButtonPushed(app, event)
            ThrustPlots(app);
        end

        % Button pushed function: EngineDataButton
        function EngineDataButtonPushed(app, event)
            EngineDataPlot(app);
        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            PurgePlot(app);
            PressuresPlots(app);
            MDotPlots(app);
            CdAsPlots(app);
            ValvesPlot(app);
            ODPPlot(app);
            FLOWPlot(app);
            ThrustPlots(app);
            EngineDataPlot(app);
        end

        % Button pushed function: SubscaleAblativeButton
        function SubscaleAblativeButtonPushed(app, event)
            app.DatasetLabel.Text = 'Dataset: Subscale Ablative';
            app.LOXInjectorCdAEditField.Value = 0.31;
            app.LOXSystemCdAEditField.Value = 0.865;
            app.FuelInjectorCdAEditField.Value = 0.269;
            app.FuelSystemCdAEditField.Value = 0.2300;
            app.VespulaCheckBox.Value = false;
        end

        % Button pushed function: VespulaCoaxButton
        function VespulaCoaxButtonPushed(app, event)
            app.DatasetLabel.Text = 'Dataset: Vespula Coax';
            app.LOXInjectorCdAEditField.Value = 0.982;
            app.LOXSystemCdAEditField.Value = 0.865;
            app.FuelInjectorCdAEditField.Value = 0.5553;
            app.FuelSystemCdAEditField.Value = 0.482;
            app.VespulaCheckBox.Value = true;
        end

        % Button pushed function: SubscaleCoaxRemachinedButton
        function SubscaleCoaxRemachinedButtonPushed(app, event)
            app.DatasetLabel.Text = 'Dataset: Subscale Coax Remachined';
            app.LOXInjectorCdAEditField.Value = 0.32;
            app.LOXSystemCdAEditField.Value = 1.002;
            app.FuelInjectorCdAEditField.Value = 0.41;
            app.FuelSystemCdAEditField.Value = 0.23;
            app.VespulaCheckBox.Value = false;
        end

        % Button pushed function: SubscaleCoaxSpring2024Button
        function SubscaleCoaxSpring2024ButtonPushed(app, event)
            app.DatasetLabel.Text = 'Dataset: Subscale Coax Spring 2024';
            app.LOXInjectorCdAEditField.Value = 0.319;
            app.LOXSystemCdAEditField.Value = 0.885;
            app.FuelInjectorCdAEditField.Value = 0.269;
            app.FuelSystemCdAEditField.Value = 0.23;
            app.VespulaCheckBox.Value = false;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.loxorfcdaTextArea.Value = app.LOXInjectorCdAEditField.Value;
            app.loxsyscdaTextArea.Value = app.LOXSystemCdAEditField.Value;
            app.fuelorfcdaTextArea.Value = app.FuelInjectorCdAEditField.Value;
            app.fuelsyscdaTextArea.Value = app.FuelSystemCdAEditField.Value;
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.OMVTimeTextAreaLabel = uilabel(app.MainTab);
            app.OMVTimeTextAreaLabel.Text = 'OMV Time';
            app.OMVTimeTextArea = uitextarea(app.MainTab);
            app.OMVTimeTextArea.Value = {'--'};
            app.FMVTimeTextArea = uitextarea(app.MainTab);
            app.FMVTimeTextArea.Value = {'--'};
            app.LOXCheckBox = uicheckbox(app.MainTab);
            app.LOXCheckBox.ValueChangedFcn = createCallbackFcn(app, @LOXCheckBoxValueChanged, true);
            app.LOXCheckBox.Text = 'LOX';
            app.LOXCheckBox.Value = true;
            app.LN2CheckBox = uicheckbox(app.MainTab);
            app.LN2CheckBox.ValueChangedFcn = createCallbackFcn(app, @LN2CheckBoxValueChanged, true);
            app.LN2CheckBox.Text = 'LN2';
            app.JetACheckBox = uicheckbox(app.MainTab);
            app.JetACheckBox.ValueChangedFcn = createCallbackFcn(app, @JetACheckBoxValueChanged, true);
            app.JetACheckBox.Text = 'Jet-A';
            app.JetACheckBox.Value = true;
            app.WaterCheckBox = uicheckbox(app.MainTab);
            app.WaterCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaterCheckBoxValueChanged, true);
            app.WaterCheckBox.Text = 'Water';
            app.datacsvEditField = uieditfield(app.MainTab, 'text');
            app.datacsvEditField.Value = 'VespulaHeatsinkFire1.csv';
            app.FMVTimeTextAreaLabel = uilabel(app.MainTab);
            app.FMVTimeTextAreaLabel.Text = 'FMV Time';
            app.datacsvEditFieldLabel = uilabel(app.MainTab);
            app.datacsvEditFieldLabel.Text = 'data file';
            app.RunButton = uibutton(app.MainTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TextArea = uitextarea(app.MainTab);
            app.TextArea.Value = {'Not running.'};
            app.PNPTTextArea = uitextarea(app.MainTab);
            app.PNPTTextArea.Value = {'--'};
            app.PNPTTextAreaLabel = uilabel(app.MainTab);
            app.PNPTTextAreaLabel.Text = 'PNPT';
            app.loxorfcdaTextAreaLabel = uilabel(app.MainTab);
            app.loxorfcdaTextAreaLabel.Text = 'LOX Orifice CdA';
            app.loxsyscdaTextAreaLabel = uilabel(app.MainTab);
            app.loxsyscdaTextAreaLabel.Text = 'LOX System CdA';
            app.venturicdTextAreaLabel = uilabel(app.MainTab);
            app.venturicdTextAreaLabel.Text = 'Venturi Cd';
            app.cm2Label = uilabel(app.MainTab);
            app.cm2Label.Text = 'cm2';
            app.cm2Label_2 = uilabel(app.MainTab);
            app.cm2Label_2.Text = 'cm2';
            app.Label = uilabel(app.MainTab);
            app.Label.Text = '--';
            app.fuelorfcdaTextAreaLabel = uilabel(app.MainTab);
            app.fuelorfcdaTextAreaLabel.Text = 'Fuel Orifice CdA';
            app.fuelsyscdaTextAreaLabel = uilabel(app.MainTab);
            app.fuelsyscdaTextAreaLabel.Text = 'Fuel System CdA';
            app.cm2Label_3 = uilabel(app.MainTab);
            app.cm2Label_3.Text = 'cm2';
            app.cm2Label_4 = uilabel(app.MainTab);
            app.cm2Label_4.Text = 'cm2';
            app.msLabel = uilabel(app.MainTab);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.MainTab);
            app.msLabel_2.Text = 'ms';
            app.psiLabel = uilabel(app.MainTab);
            app.psiLabel.Text = 'psi';
            app.loxorfcdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.loxsyscdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.venturicdTextArea = uieditfield(app.MainTab, 'numeric');
            app.venturicdTextArea.Value = 0.978;
            app.fuelorfcdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.fuelsyscdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.SenseBreakTextArea = uitextarea(app.MainTab);
            app.SenseBreakTextArea.Value = {'--'};
            app.SenseBreakTextAreaLabel = uilabel(app.MainTab);
            app.SenseBreakTextAreaLabel.Text = 'Sense Break Time';
            app.sLabel = uilabel(app.MainTab);
            app.sLabel.Text = 's';
            app.omvflowdurTextArea = uitextarea(app.MainTab);
            app.omvflowdurTextArea.Value = {'--'};
            app.omvflowdurTextAreaLabel = uilabel(app.MainTab);
            app.omvflowdurTextAreaLabel.Text = 'OMV Flow Duration';
            app.sLabel_2 = uilabel(app.MainTab);
            app.sLabel_2.Text = 's';
            app.fmvflowdurTextArea = uitextarea(app.MainTab);
            app.fmvflowdurTextArea.Value = {'--'};
            app.fmvflowdurTextAreaLabel = uilabel(app.MainTab);
            app.fmvflowdurTextAreaLabel.Text = 'FMV Flow Duration';
            app.sLabel_3 = uilabel(app.MainTab);
            app.sLabel_3.Text = 's';
            app.SamplingRateTextArea = uitextarea(app.MainTab);
            app.SamplingRateTextArea.Value = {'--'};
            app.SamplingRateTextAreaLabel = uilabel(app.MainTab);
            app.SamplingRateTextAreaLabel.Text = 'Sampling Rate';
            app.HzLabel = uilabel(app.MainTab);
            app.HzLabel.Text = 'Hz';
            app.TEditFieldLabel = uilabel(app.MainTab);
            app.TEditFieldLabel.Text = 'T-';
            app.TEditField = uieditfield(app.MainTab, 'numeric');
            app.TEditField.Value = 2;
            app.TLabel = uilabel(app.MainTab);
            app.TLabel.Text = 'T+';
            app.TEditField_2 = uieditfield(app.MainTab, 'numeric');
            app.TEditField_2.Value = 8;
            app.OrificeandinjectorusedinterchangablyinoutputsLabel = uilabel(app.MainTab);
            app.OrificeandinjectorusedinterchangablyinoutputsLabel.Text = '*Orifice and injector used interchangably in outputs';
            app.madebyJacksonStahlLabel = uilabel(app.MainTab);
            app.madebyJacksonStahlLabel.Text = 'made by Jackson Stahl';
            app.InputsLabel = uilabel(app.MainTab);
            app.InputsLabel.Text = 'Inputs:';
            app.v0241Label = uilabel(app.MainTab);
            app.v0241Label.Text = 'v0.2.4.1';
            app.LOXLeadLabel = uilabel(app.MainTab);
            app.LOXLeadLabel.Text = 'LOX Lead';
            app.LOXLeadEditField = uieditfield(app.MainTab, 'numeric');
            app.LOXLeadEditField.Value = 200;
            app.OMVTimeEditFieldLabel = uilabel(app.MainTab);
            app.OMVTimeEditFieldLabel.Text = 'OMV Time';
            app.OMVTimeEditField = uieditfield(app.MainTab, 'numeric');
            app.OMVTimeEditField.Value = 500;
            app.FMVTimeEditFieldLabel = uilabel(app.MainTab);
            app.FMVTimeEditFieldLabel.Text = 'FMV Time';
            app.FMVTimeEditField = uieditfield(app.MainTab, 'numeric');
            app.FMVTimeEditField.Value = 690;
            app.msLabel_3 = uilabel(app.MainTab);
            app.msLabel_3.Text = 'ms';
            app.LOXLeadEditField_3Label = uilabel(app.MainTab);
            app.LOXLeadEditField_3Label.Text = 'LOX Lead';
            app.LOXLeadEditField_3 = uieditfield(app.MainTab, 'text');
            app.LOXLeadEditField_3.Value = '--';
            app.OutputsLabel = uilabel(app.MainTab);
            app.OutputsLabel.Text = 'Outputs:';
            app.VespulaCheckBox = uicheckbox(app.MainTab);
            app.VespulaCheckBox.Text = 'Vespula';
            app.fmmultiplierTextAreaLabel = uilabel(app.MainTab);
            app.fmmultiplierTextAreaLabel.Text = 'Flow Meter Multiplier';
            app.Label_2 = uilabel(app.MainTab);
            app.Label_2.Text = '--';
            app.fmmultiplierTextArea = uieditfield(app.MainTab, 'numeric');
            app.fmmultiplierTextArea.Value = 1;
            app.LOXdensityEditFieldLabel = uilabel(app.MainTab);
            app.LOXdensityEditFieldLabel.Text = 'LOX density';
            app.LOXdensityEditField = uieditfield(app.MainTab, 'numeric');
            app.LOXdensityEditField.Value = 1070;
            app.kgm3Label = uilabel(app.MainTab);
            app.kgm3Label.Text = 'kg/m3';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.PlotAllButton = uibutton(app.PlotsTab, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Text = 'Plot All';
            app.PurgeButton = uibutton(app.PlotsTab, 'push');
            app.PurgeButton.ButtonPushedFcn = createCallbackFcn(app, @PurgeButtonPushed, true);
            app.PurgeButton.Text = 'Purge';
            app.PressureLaddersButton = uibutton(app.PlotsTab, 'push');
            app.PressureLaddersButton.ButtonPushedFcn = createCallbackFcn(app, @PressureLaddersButtonPushed, true);
            app.PressureLaddersButton.Text = 'Pressure Ladders';
            app.mdotsButton = uibutton(app.PlotsTab, 'push');
            app.mdotsButton.ButtonPushedFcn = createCallbackFcn(app, @mdotsButtonPushed, true);
            app.mdotsButton.Text = 'mdots';
            app.CdAsButton = uibutton(app.PlotsTab, 'push');
            app.CdAsButton.ButtonPushedFcn = createCallbackFcn(app, @CdAsButtonPushed, true);
            app.CdAsButton.Text = 'CdAs';
            app.ValvesButton = uibutton(app.PlotsTab, 'push');
            app.ValvesButton.ButtonPushedFcn = createCallbackFcn(app, @ValvesButtonPushed, true);
            app.ValvesButton.Text = 'Valves';
            app.ODPButton = uibutton(app.PlotsTab, 'push');
            app.ODPButton.ButtonPushedFcn = createCallbackFcn(app, @ODPButtonPushed, true);
            app.ODPButton.Text = 'ODP';
            app.FLOWButton = uibutton(app.PlotsTab, 'push');
            app.FLOWButton.ButtonPushedFcn = createCallbackFcn(app, @FLOWButtonPushed, true);
            app.FLOWButton.Text = 'FLOW';
            app.ThrustButton = uibutton(app.PlotsTab, 'push');
            app.ThrustButton.ButtonPushedFcn = createCallbackFcn(app, @ThrustButtonPushed, true);
            app.ThrustButton.Text = 'Thrust';
            app.PRPT1CheckBox = uicheckbox(app.PlotsTab);
            app.PRPT1CheckBox.Text = 'PRPT1';
            app.PRPT2CheckBox = uicheckbox(app.PlotsTab);
            app.PRPT2CheckBox.Text = 'PRPT2';
            app.OTPTCheckBox = uicheckbox(app.PlotsTab);
            app.OTPTCheckBox.Text = 'OTPT';
            app.OTPTCheckBox.Value = true;
            app.ORUNPTCheckBox = uicheckbox(app.PlotsTab);
            app.ORUNPTCheckBox.Text = 'ORUNPT';
            app.ORUNPTCheckBox.Value = true;
            app.OIPTCheckBox = uicheckbox(app.PlotsTab);
            app.OIPTCheckBox.Text = 'OIPT';
            app.OIPTCheckBox.Value = true;
            app.FTPTCheckBox = uicheckbox(app.PlotsTab);
            app.FTPTCheckBox.Text = 'FTPT';
            app.FTPTCheckBox.Value = true;
            app.FRUNPTCheckBox = uicheckbox(app.PlotsTab);
            app.FRUNPTCheckBox.Text = 'FRUNPT';
            app.FRUNPTCheckBox.Value = true;
            app.FIPTCheckBox = uicheckbox(app.PlotsTab);
            app.FIPTCheckBox.Text = 'FIPT';
            app.FIPTCheckBox.Value = true;
            app.CHPTCheckBox = uicheckbox(app.PlotsTab);
            app.CHPTCheckBox.Text = 'CHPT';
            app.CHPTCheckBox.Value = true;
            app.FLPTCheckBox = uicheckbox(app.PlotsTab);
            app.FLPTCheckBox.Text = 'FLPT';
            app.FINPTCheckBox = uicheckbox(app.PlotsTab);
            app.FINPTCheckBox.Text = 'FINPT';
            app.ShowInputsCheckBox = uicheckbox(app.PlotsTab);
            app.ShowInputsCheckBox.Text = 'Show Inputs';
            app.CloseAllButton = uibutton(app.PlotsTab, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Text = 'Close All';
            app.EngineDataButton = uibutton(app.PlotsTab, 'push');
            app.EngineDataButton.ButtonPushedFcn = createCallbackFcn(app, @EngineDataButtonPushed, true);
            app.EngineDataButton.Text = 'Engine Data';
            app.avgloxmdotorfTextArea = uitextarea(app.PlotsTab);
            app.avgloxmdotorfTextArea.Value = {'--'};
            app.avgloxmdotorfTextAreaLabel = uilabel(app.PlotsTab);
            app.avgloxmdotorfTextAreaLabel.Text = 'Avg LOX Mdot (Orf CdA)';
            app.kgsLabel = uilabel(app.PlotsTab);
            app.kgsLabel.Text = 'kg/s';
            app.avgloxmdotsysTextArea = uitextarea(app.PlotsTab);
            app.avgloxmdotsysTextArea.Value = {'--'};
            app.avgloxmdotsysTextAreaLabel = uilabel(app.PlotsTab);
            app.avgloxmdotsysTextAreaLabel.Text = 'Avg LOX Mdot (Sys CdA)';
            app.kgsLabel_2 = uilabel(app.PlotsTab);
            app.kgsLabel_2.Text = 'kg/s';
            app.avgloxmdotventuriTextArea = uitextarea(app.PlotsTab);
            app.avgloxmdotventuriTextArea.Value = {'--'};
            app.avgloxmdotventuriTextAreaLabel = uilabel(app.PlotsTab);
            app.avgloxmdotventuriTextAreaLabel.Text = 'Avg LOX Mdot (Venturi)';
            app.kgsLabel_3 = uilabel(app.PlotsTab);
            app.kgsLabel_3.Text = 'kg/s';
            app.avgfuelmdotorfTextArea = uitextarea(app.PlotsTab);
            app.avgfuelmdotorfTextArea.Value = {'--'};
            app.avgfuelmdotorfTextAreaLabel = uilabel(app.PlotsTab);
            app.avgfuelmdotorfTextAreaLabel.Text = 'Avg Fuel Mdot (Orf CdA)';
            app.kgsLabel_4 = uilabel(app.PlotsTab);
            app.kgsLabel_4.Text = 'kg/s';
            app.avgfuelmdotsysTextArea = uitextarea(app.PlotsTab);
            app.avgfuelmdotsysTextArea.Value = {'--'};
            app.avgfuelmdotsysTextAreaLabel = uilabel(app.PlotsTab);
            app.avgfuelmdotsysTextAreaLabel.Text = 'Avg Fuel Mdot (Sys CdA)';
            app.kgsLabel_5 = uilabel(app.PlotsTab);
            app.kgsLabel_5.Text = 'kg/s';
            app.avgfuelmdotfmTextArea = uitextarea(app.PlotsTab);
            app.avgfuelmdotfmTextArea.Value = {'--'};
            app.avgfuelmdotfmTextAreaLabel = uilabel(app.PlotsTab);
            app.avgfuelmdotfmTextAreaLabel.Text = 'Avg Fuel Mdot (Flow Meter)';
            app.kgsLabel_6 = uilabel(app.PlotsTab);
            app.kgsLabel_6.Text = 'kg/s';
            app.cm2Label_5 = uilabel(app.PlotsTab);
            app.cm2Label_5.Text = 'cm2';
            app.cm2Label_6 = uilabel(app.PlotsTab);
            app.cm2Label_6.Text = 'cm2';
            app.cm2Label_7 = uilabel(app.PlotsTab);
            app.cm2Label_7.Text = 'cm2';
            app.cm2Label_8 = uilabel(app.PlotsTab);
            app.cm2Label_8.Text = 'cm2';
            app.cm2Label_9 = uilabel(app.PlotsTab);
            app.cm2Label_9.Text = 'cm2';
            app.cm2Label_10 = uilabel(app.PlotsTab);
            app.cm2Label_10.Text = 'cm2';
            app.cm2Label_11 = uilabel(app.PlotsTab);
            app.cm2Label_11.Text = 'cm2';
            app.cm2Label_12 = uilabel(app.PlotsTab);
            app.cm2Label_12.Text = 'cm2';
            app.AvgLOXSysCdAOrificeEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgLOXSysCdAOrificeEditFieldLabel.Text = 'Avg LOX Sys CdA (Orifice)';
            app.AvgLOXSysCdAOrificeEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgLOXSysCdAOrificeEditField.Value = '--';
            app.AvgLOXSysCdAVenturiEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgLOXSysCdAVenturiEditFieldLabel.Text = 'Avg LOX Sys CdA (Venturi)';
            app.AvgLOXSysCdAVenturiEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgLOXSysCdAVenturiEditField.Value = '--';
            app.AvgLOXOrfCdASysCdAEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgLOXOrfCdASysCdAEditFieldLabel.Text = 'Avg LOX Orf CdA (Sys CdA)';
            app.AvgLOXOrfCdASysCdAEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgLOXOrfCdASysCdAEditField.Value = '--';
            app.AvgLOXOrfCdAVenturiEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgLOXOrfCdAVenturiEditFieldLabel.Text = 'Avg LOX Orf CdA (Venturi)';
            app.AvgLOXOrfCdAVenturiEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgLOXOrfCdAVenturiEditField.Value = '--';
            app.AvgFuelSysCdAOrificeEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgFuelSysCdAOrificeEditFieldLabel.Text = 'Avg Fuel Sys CdA (Orifice)';
            app.AvgFuelSysCdAOrificeEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgFuelSysCdAOrificeEditField.Value = '--';
            app.AvgFuelOrfCdASysCdAEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgFuelOrfCdASysCdAEditFieldLabel.Text = 'Avg Fuel Orf CdA (Sys CdA)';
            app.AvgFuelOrfCdASysCdAEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgFuelOrfCdASysCdAEditField.Value = '--';
            app.AvgFuelSysCdAFlowMeterEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgFuelSysCdAFlowMeterEditFieldLabel.Text = 'Avg Fuel Sys CdA (Flow Meter)';
            app.AvgFuelSysCdAFlowMeterEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgFuelSysCdAFlowMeterEditField.Value = '--';
            app.AvgFuelOrfCdAFlowMeterEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgFuelOrfCdAFlowMeterEditFieldLabel.Text = 'Avg Fuel Orf CdA (Flow Meter)';
            app.AvgFuelOrfCdAFlowMeterEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgFuelOrfCdAFlowMeterEditField.Value = '--';
            app.SwitchORUNandFRUNcheckNotionCheckBox = uicheckbox(app.PlotsTab);
            app.SwitchORUNandFRUNcheckNotionCheckBox.Text = 'Switch ORUN and FRUN - check Notion';
            app.LoadInputsTab = uitab(app.TabGroup);
            app.LoadInputsTab.Title = 'Load Inputs';
            app.SubscaleAblativeButton = uibutton(app.LoadInputsTab, 'push');
            app.SubscaleAblativeButton.ButtonPushedFcn = createCallbackFcn(app, @SubscaleAblativeButtonPushed, true);
            app.SubscaleAblativeButton.Text = 'Subscale Ablative';
            app.VespulaCoaxButton = uibutton(app.LoadInputsTab, 'push');
            app.VespulaCoaxButton.ButtonPushedFcn = createCallbackFcn(app, @VespulaCoaxButtonPushed, true);
            app.VespulaCoaxButton.Text = 'Vespula Coax';
            app.DatasetLabel = uilabel(app.LoadInputsTab);
            app.DatasetLabel.Text = 'Dataset:';
            app.LOXInjectorCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.LOXInjectorCdAEditFieldLabel.Text = 'LOX Injector CdA';
            app.LOXInjectorCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.LOXSystemCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.LOXSystemCdAEditFieldLabel.Text = 'LOX System CdA';
            app.LOXSystemCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.FuelInjectorCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.FuelInjectorCdAEditFieldLabel.Text = 'Fuel Injector CdA';
            app.FuelInjectorCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.FuelSystemCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.FuelSystemCdAEditFieldLabel.Text = 'Fuel System CdA';
            app.FuelSystemCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.LoadButton = uibutton(app.LoadInputsTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SubscaleCoaxRemachinedButton = uibutton(app.LoadInputsTab, 'push');
            app.SubscaleCoaxRemachinedButton.ButtonPushedFcn = createCallbackFcn(app, @SubscaleCoaxRemachinedButtonPushed, true);
            app.SubscaleCoaxRemachinedButton.Text = 'Subscale Coax Remachined';
            app.SubscaleCoaxSpring2024Button = uibutton(app.LoadInputsTab, 'push');
            app.SubscaleCoaxSpring2024Button.ButtonPushedFcn = createCallbackFcn(app, @SubscaleCoaxSpring2024ButtonPushed, true);
            app.SubscaleCoaxSpring2024Button.Text = 'Subscale Coax Spring 2024';
            app.ChangelogTab = uitab(app.TabGroup);
            app.ChangelogTab.Title = 'Changelog';
            app.TextArea_2 = uitextarea(app.ChangelogTab);
            app.TextArea_2.Value = {'v0.2.4.1'; 'Can use .mat files'; ''; 'v0.2.4'; 'Updated V. heatsink dataset inputs because I did it wrong the first time'; ''; 'v0.2.3:'; 'Updated V. heatsink dataset inputs'; 'Added LOX density box'; ''; 'v0.2.2:'; 'Fixed subscale ablative dataset inputs'; ''; 'v0.2.1:'; 'Can accept .csv or .mat files'; 'Fixed the Running... box'; 'Added changelog'; ''; 'v0.2:'; 'CdA plots use injpt-chpt for calculations'; 'Pressure ladder plot can switch ORUN and FRUN for V. Heatsink'; 'Fixed thrust plot - legend colors and avg thrust are correct'; ''; 'v0.1:'; 'First release'; ''; 'Future:'; 'Fix fm average again. It is more accurate than previous fm avg, but was low for V. Heatsink'; 'Ability to load files from file explorer'; 'Add expected values on mdot graphs, pressure ladder, CdA'; 'Tare pressures in pressure ladder'; 'Show inputs on CdA plot'; 'Fix sense wire break time calculation'; 'Calculate LOX density'; 'Troubleshoot .mat files'; ''; 'If you would like a change to be made, message Jackson Stahl on Teams.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StahlDataAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end